classdef Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MaximumintensityprojectionMIPPanel  matlab.ui.container.Panel
        FFCMMRFsegmentationPanel  matlab.ui.container.Panel
        DiameterPanel             matlab.ui.container.Panel
        mmLabel                   matlab.ui.control.Label
        SubjectEditFieldLabel     matlab.ui.control.Label
        SubjectEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Callapp
        
        
    end
    
    methods (Access = public)
        
        function Result_plot(app,plot_image,step_num)
            switch step_num
                case 1 %skull-stripping:第一个是原图，第二个是mask
                    with_mask_plot(app,plot_image{1},plot_image{2},app.SkullstrippingPanel,0,0);
                case 2%规范化后的MIP
                    without_mask_plot(app,plot_image,app.MaximumintensityprojectionMIPPanel);
                case 3%MRF
                    without_mask_plot(app,plot_image{1},app.MaximumintensityprojectionMIPPanel);
                    with_mask_plot(app,plot_image{1},plot_image{2},app.FFCMMRFsegmentationPanel,1,0);
                case 4%血管直径图
                    with_mask_plot(app,plot_image{1},plot_image{2},app.DiameterPanel,1,1);
                
            end
            
        end
        function Subject_name_appearance(app,subject_name)%2023/06/18:显示被试序号
            app.SubjectEditField.Value=subject_name;   %2023/06/18:显示被试序号   
        end%2023/06/18:显示被试序号
        function with_mask_plot(app,image_path,mask_path,panel,is_MIP,is_diameters)%在哪个panel画，是否画MIP图（此处不包括3D图绘制）
            info=load_untouch_nii(image_path);
            img=info.img;
            mask_info=load_untouch_nii(mask_path);
            mask_img=mask_info.img;
            if ~is_MIP
                % 冠状面
                ax=axes(panel,'position',[0.1 0.35 0.8 1.05]);
                re_img=imresize(squeeze(img(:,224,:)),[448 256]);
                img_=rot90(re_img);
                
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                mask_re_img=imresize(squeeze(mask_img(:,224,:)),[448 256]);
                mask_img_=rot90(mask_re_img);
                mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的

                mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
                %矢状面
                ax=axes(panel,'position',[0.1 0.095 0.8 1.05]);
                re_img=imresize(squeeze(img(224,:,:)),[448 256]);
                img_=rot90(re_img);
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                mask_re_img=imresize(squeeze(mask_img(224,:,:)),[448 256]);
                mask_img_=rot90(mask_re_img);
                mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
                mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));          
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
                %轴状面
                ax=axes(panel,'Position',[0.1 -0.305 0.8 1.11]);
                re_img=imresize(img(:,:,64),[448 425]);
                img_=rot90(re_img,-1);
                img_=imcrop(img_,[50 40 350 450]);
                
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                mask_re_img=imresize(mask_img(:,:,64),[448 425]);
                mask_img_=rot90(mask_re_img,-1);
                mask_img_=imcrop(mask_img_,[50 40 350 450]);
                class(mask_img_)
                mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
                mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[])   
            else
                if is_diameters%2023/06/19:增加血管直径的colorbar
                    %colorbar
                    ax_color=axes(app.DiameterPanel,'position',[-0.03 0.1 1.05 0.8]);
                    colormap(ax_color,jet)%2023/06/24:colorbar改成连续的
                    colorbar(ax_color)
%                     max_color=max(int32(mask_img(:)));
%                     disp(max_color)
                    caxis(ax_color,[0 4])%*********colorbar最大值修改
%                     set(colorbar(ax_color),'YTick',[0,0.1,0.2,0.3,0.4,0.5,0.6,0.7,0.8,0.9,1],'YTickLabel',{'0','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5'})
                    set(ax_color, 'XTickLabel', [],'XTick',[]) 
                end
                %冠状面
                if is_diameters%2023/06/19:血管直径图的布局略微移动
                    ax=axes(panel,'position',[0 0.35 0.8 1.05]);
                else
                    ax=axes(panel,'position',[0.1 0.35 0.8 1.05]);
                end
                
                
                re_img=imresize(squeeze(max(img,[],2)),[448 256]);
                img_=rot90(re_img);
                
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                vessel_re_img=imresize(squeeze(max(mask_img,[],2)),[448 256]);
                vessel_img_=rot90(vessel_re_img);
                vessel_img_=int32(vessel_img_);
                if is_diameters
                    vessel_img_rgb=ind2rgb(vessel_img_,jet(4));%*******2023/06/24:修改了colorbar
                else
                    vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
                end
                
                vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
%                 if is_colorbar
%                     
%                 end
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
                %矢状面
                if is_diameters%2023/06/19:血管直径图的布局略微移动
                    ax=axes(panel,'position',[0 0.095 0.8 1.05]);
                else
                    ax=axes(panel,'position',[0.1 0.095 0.8 1.05]);
                end
                
                
                re_img=imresize(squeeze(max(img,[],1)),[448 256]);
                img_=rot90(re_img);
                
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                vessel_re_img=imresize(squeeze(max(mask_img,[],1)),[448 256]);
                vessel_img_=rot90(vessel_re_img);
                vessel_img_=int32(vessel_img_);
                
                if is_diameters
                    vessel_img_rgb=ind2rgb(vessel_img_,jet(4));%*******2023/06/24:修改了colorbar
                else
                    vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
                end
                vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
                %轴状面
                if is_diameters%2023/06/19:血管直径图的布局略微移动
                    ax=axes(panel,'position',[0 -0.3 0.8 1.1]);
                else
                    ax=axes(panel,'position',[0.1 -0.3 0.8 1.1]);
                end
                
                
                re_img=imresize(squeeze(max(img,[],3)),[448 448]);
                img_=rot90(re_img,-1);
                img_=imcrop(img_,[50 60 350 450]);
                
                imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
                hold(ax,'on');
                vessel_re_img=imresize(squeeze(max(mask_img,[],3)),[448 448]);
                vessel_img_=rot90(vessel_re_img,-1);
                vessel_img_=imcrop(vessel_img_,[50 60 350 450]);
                vessel_img_=int32(vessel_img_);
                
                if is_diameters
                    vessel_img_rgb=ind2rgb(vessel_img_,jet(4));%*******2023/06/24:修改了colorbar
                else
                    vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
                end
    %             
    %             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
                vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
                set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            end
        end
        function without_mask_plot(app,image_path,panel)
            info=load_untouch_nii(image_path);
            img=info.img;
            %冠状面
            ax=axes(panel,'position',[0.1 0.35 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],2)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %矢状面
            ax=axes(panel,'position',[0.1 0.095 0.8 1.05]);
            re_img=imresize(squeeze(max(img,[],1)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %轴状面
            ax=axes(panel,'position',[0.1 -0.3 0.8 1.1]);
            
            re_img=imresize(squeeze(max(img,[],3)),[448 448]);
            img_=rot90(re_img,-1);
            img_=imcrop(img_,[50 60 350 450]);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Callapp=mainapp;
%             app.SubjectEditField.Value=subject_name;
        end

        % Callback function
        function ButtonPushed(app, event)
            mask_info=load_untouch_nii('C:\Users\86133\Documents\pre-graduate-tasks\app_test\pre-process-test\Bet_049_mask.nii.gz');
            mask_img=mask_info.img;
            mask_hdr=mask_info.hdr;
            info=load_untouch_nii('C:\Users\86133\Documents\pre-graduate-tasks\app_test\pre-process-test\Normal049-MRA.nii.gz');
            img=info.img;
            hdr=info.hdr;
            
            ax=axes(app.SkullstrippingPanel,'position',[0.1 0.35 0.8 1.05]);
            re_img=imresize(squeeze(img(:,224,:)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            mask_re_img=imresize(squeeze(mask_img(:,224,:)),[448 256]);
            mask_img_=rot90(mask_re_img);
            mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
            
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
%             colorbar(ax)
%             h=imshow(mask_image,'InitialMagnification','fit','Parent',ax); 
%             h=imshow(mat2gray(mask_img_),[0 0 0;1 1 0],'InitialMagnification','fit','Parent',ax); 
%             set(h,'AlphaData',0.3*double(mask_img_>0));
%             set(h,'AlphaData',0.3);
            
            
%                     title('Coronal');
            
%                     subplot('Position',[0.5 0.4 0.45 0.85]);
%                     set(gca,'position', [0.5 0.4 0.45 0.85]);
%                     nexttile(t,2)
%                     axes(ha(2));
%                     subplot(2,2,3)
            ax=axes(app.SkullstrippingPanel,'position',[0.1 0.095 0.8 1.05]);
            
            re_img=imresize(squeeze(img(224,:,:)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            mask_re_img=imresize(squeeze(mask_img(224,:,:)),[448 256]);
            mask_img_=rot90(mask_re_img);
            mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
            mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));
%             h=imshow(mat2gray(mask_img_),'InitialMagnification','fit','Parent',ax); 
%             set(h,'AlphaData',0.3*double(mask_img_>0));
            
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
% %                     title('Sagittal');
% %                     subplot("Position",[0.04 0 0.4 1]);
% %                     set(gca,'position', [0.04 0.1 0.45 0.85]);
% %                     nexttile(t,3)
% %                     axes(ha(3));
% %                     subplot(2,2,[2,4])
            ax=axes(app.SkullstrippingPanel,'Position',[0.1 -0.305 0.8 1.11]);
            re_img=imresize(img(:,:,64),[448 425]);
            img_=rot90(re_img,-1);
            img_=imcrop(img_,[50 40 350 450]);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            mask_re_img=imresize(mask_img(:,:,64),[448 425]);
            mask_img_=rot90(mask_re_img,-1);
            mask_img_=imcrop(mask_img_,[50 40 350 450]);
            class(mask_img_)
            mask_img_rgb=ind2rgb(mask_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
            mask_image=image(ax,mask_img_rgb,'AlphaData',0.5*double(mask_img_>0));
%             h=imshow(mat2gray(mask_img_),'InitialMagnification','fit','Parent',ax); 
%             set(h,'AlphaData',0.3*double(mask_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[])   
        end

        % Callback function
        function Button2Pushed(app, event)
            vessel_info=load_untouch_nii('C:\Users\86133\Documents\MATLAB\graduate-tasks\Matched-141\Matched001-MRA_MRF.nii.gz');
            vessel_img=vessel_info.img;
            vessel_hdr=vessel_info.hdr;
            info=load_untouch_nii('C:\Users\86133\Documents\MATLAB\graduate-tasks\Matched-141\Matched001-MRA.nii.gz');
            img=info.img;
            hdr=info.hdr;
            %冠状面
            ax=axes(app.FFCMMRFsegmentationPanel,'position',[0.1 0.35 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],2)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],2)),[448 256]);
            vessel_img_=rot90(vessel_re_img);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %矢状面
            ax=axes(app.FFCMMRFsegmentationPanel,'position',[0.1 0.095 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],1)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],1)),[448 256]);
            vessel_img_=rot90(vessel_re_img);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %轴状面
            ax=axes(app.FFCMMRFsegmentationPanel,'position',[0.1 -0.3 0.8 1.1]);
            
            re_img=imresize(squeeze(max(img,[],3)),[448 448]);
            img_=rot90(re_img,-1);
            img_=imcrop(img_,[50 60 350 450]);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],3)),[448 448]);
            vessel_img_=rot90(vessel_re_img,-1);
            vessel_img_=imcrop(vessel_img_,[50 60 350 450]);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            
%             %3D血管可视化
%             ax=axes(app.FFCMMRFsegmentationPanel,'position',[0.1 -0.17 0.65 0.65]);
%             set(ax,'color','white'); 
%             [a,b,c] = size(vessel_img);
%             Vessel_maxConnect=(vessel_img)>0;
%             patch(ax,isosurface(Vessel_maxConnect,0.5),'FaceColor',[1,0,0],'EdgeColor','none');
%             axis(ax,[0 b 0 a 0 c]);view(ax,[270,270]);
%             daspect(ax,[1 1 1]);camlight(ax,-80,-10); lighting(ax,'phong');
%             set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
%             
%             %用scatter3试试，在非零处画点
% 
%             AA=vessel_img(vessel_img~=0);
%             AA_notzero=sum(AA(:));
%             X=zeros(AA_notzero);
%             Y=zeros(AA_notzero);
%             Z=zeros(AA_notzero);
%             
%             [a,b,c] = size(vessel_img);%x,y,z
%             count_=0;
%             for x=1:a
%                 for y=1:b
%                     for z=1:c
%                         if vessel_img(x,y,z)>0
%                             X(count_)=x;
%                             Y(count_)=y;
%                             Z(count_)=z;
%                             count_=count_+1;
%                         end
%                     end
%                 end
%             end
%             scatter3(X,Y,Z,15,'r','filled');
%             axis([0 b 0 a 0 c]);
            

            %冠状面
            ax=axes(app.MaximumintensityprojectionMIPPanel,'position',[0.1 0.35 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],2)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
%             hold(ax,'on');
%             vessel_re_img=imresize(squeeze(max(vessel_img,[],2)),[448 256]);
%             vessel_img_=rot90(vessel_re_img);
%             vessel_img_=int32(vessel_img_);
%             
%             vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
% %             
% %             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
%             vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %矢状面
            ax=axes(app.MaximumintensityprojectionMIPPanel,'position',[0.1 0.095 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],1)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
%             hold(ax,'on');
%             vessel_re_img=imresize(squeeze(max(vessel_img,[],1)),[448 256]);
%             vessel_img_=rot90(vessel_re_img);
%             vessel_img_=int32(vessel_img_);
%             
%             vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
% %             
% %             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
%             vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %轴状面
            ax=axes(app.MaximumintensityprojectionMIPPanel,'position',[0.1 -0.3 0.8 1.1]);
            
            re_img=imresize(squeeze(max(img,[],3)),[448 448]);
            img_=rot90(re_img,-1);
            img_=imcrop(img_,[50 60 350 450]);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
%             hold(ax,'on');
%             vessel_re_img=imresize(squeeze(max(vessel_img,[],3)),[448 528]);
%             vessel_img_=rot90(vessel_re_img,-1);
%             vessel_img_=imcrop(vessel_img_,[50 60 350 450]);
%             vessel_img_=int32(vessel_img_);
%             
%             vessel_img_rgb=ind2rgb(vessel_img_,autumn);%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
% %             
% %             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
%             vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            
        end

        % Callback function
        function Button3Pushed(app, event)
            vessel_info=load_untouch_nii('C:\Users\86133\Documents\pre-graduate-tasks\TOF-MRA\MIDAS_analysis\M001\metrics\diameter.nii.gz');
            vessel_img=vessel_info.img;
            vessel_hdr=vessel_info.hdr;
            info=load_untouch_nii('C:\Users\86133\Documents\pre-graduate-tasks\TOF-MRA\MIDAS_analysis\M001\metrics\brain_resample.nii.gz');
            img=info.img;
            hdr=info.hdr;
            %冠状面
            ax=axes(app.DiameterPanel,'position',[0.1 0.35 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],2)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],2)),[448 256]);
            vessel_img_=rot90(vessel_re_img);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,jet(7));%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %矢状面
            ax=axes(app.DiameterPanel,'position',[0.1 0.095 0.8 1.05]);
            
            re_img=imresize(squeeze(max(img,[],1)),[448 256]);
            img_=rot90(re_img);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],1)),[448 256]);
            vessel_img_=rot90(vessel_re_img);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,jet(7));%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
            vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            %轴状面
            ax=axes(app.DiameterPanel,'position',[0.1 -0.3 0.8 1.1]);
            
            re_img=imresize(squeeze(max(img,[],3)),[448 448]);
            img_=rot90(re_img,-1);
            img_=imcrop(img_,[50 60 350 450]);
            
            imshow(mat2gray(img_),'InitialMagnification','fit','Parent',ax)
            hold(ax,'on');
            vessel_re_img=imresize(squeeze(max(vessel_img,[],3)),[448 448]);
            vessel_img_=rot90(vessel_re_img,-1);
            vessel_img_=imcrop(vessel_img_,[50 60 350 450]);
            vessel_img_=int32(vessel_img_);
            
            vessel_img_rgb=ind2rgb(vessel_img_,jet(7));%转成rgb图像，不能依靠colormap映射，因为colormap是作用于axes对象的
%             
%             imagesc(ax,mask_img_,'AlphaData',0.3*double(mask_img_>0));
%             vessel_image=image(ax,vessel_img_rgb,'AlphaData',0.5*double(vessel_img_>0));
            h=imshow(vessel_img_rgb,'InitialMagnification','fit','Parent',ax,'Colormap',jet(7)); 
            set(h,'AlphaData',0.5*double(vessel_img_>0));
%             colorbar(h)
%             colorbar(h);
%             colorbar(vessel_img_rgb)
            set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
            
            
%             %3D血管可视化
%             ax=axes(app.DiameterPanel,'position',[0.1 -0.17 0.65 0.65]);
%             set(ax,'color','white'); 
%             [a,b,c] = size(vessel_img);
%             Vessel_maxConnect=(vessel_img)>0;
%             patch(ax,isosurface(Vessel_maxConnect,0.5),'FaceColor',[1 0 0],'EdgeColor','none');
%             axis(ax,[0 b 0 a 0 c]);view(ax,[270,270]);
%             daspect(ax,[1 1 1]);camlight(ax,-80,-10); lighting(ax,'phong');
% 
%             set(ax, 'XTickLabel', [],'XTick',[],'YTickLabel', [],'YTick',[]) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaximumintensityprojectionMIPPanel = uipanel(app.UIFigure);
            app.MaximumintensityprojectionMIPPanel.Title = 'Maximum intensity projection (MIP)';
            app.FFCMMRFsegmentationPanel = uipanel(app.UIFigure);
            app.FFCMMRFsegmentationPanel.Title = 'FFCM-MRF segmentation';
            app.DiameterPanel = uipanel(app.UIFigure);
            app.DiameterPanel.Title = 'Diameter';
            app.mmLabel = uilabel(app.DiameterPanel);
            app.mmLabel.Text = '(mm)';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject:';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end